classdef HW4_Code < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TextArea               matlab.ui.control.TextArea
        ClearTableButton       matlab.ui.control.Button
        Graph                  matlab.ui.control.UIAxes
        PlotButton             matlab.ui.control.Button
        CitiesListBoxLabel     matlab.ui.control.Label
        CitiesListBox          matlab.ui.control.ListBox
        StartYearSpinnerLabel  matlab.ui.control.Label
        StartYearSpinner       matlab.ui.control.Spinner
        EndYearSpinnerLabel    matlab.ui.control.Label
        EndYearSpinner         matlab.ui.control.Spinner
        CollectDataButton      matlab.ui.control.Button
        WelcomeText            matlab.ui.control.TextArea
        TimeRangeInfo          matlab.ui.control.TextArea
        Wait                   matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        structproj % Description
    end
    methods (Access = private)
        
        function cities = CitiesValueChanged(app) %This function grabs the values of the CitiesListBox, which gives the cities chosen by the user.
            cities = app.CitiesListBox.Value;
        end
        function Range = Rangefunct(app) %This function takes the values of the two Year spinners and creates a range for it.
            Start = app.StartYearSpinner.Value;
            End = app.EndYearSpinner.Value;
            Range = (Start : End);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ClearTableButton
        function ClearTableButtonPushed(app, event)
            cla(app.Graph,'reset');
            app.Graph.Visible = 'off';
            app.CitiesListBox.Visible = 'on';
            app.StartYearSpinner.Visible = 'on';
            app.EndYearSpinner.Visible = 'on';
            app.TextArea.Visible = 'on';
            app.TimeRangeInfo.Visible = 'on';
            app.PlotButton.Visible = 'on';
            app.ClearTableButton.Visible = 'off';
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.Wait.Visible = 'on';
            app.TimeRangeInfo.Visible = 'off';
            app.CitiesListBox.Visible = 'off';
            app.StartYearSpinner.Visible = 'off';
            app.EndYearSpinner.Visible = 'off';
            app.TextArea.Visible = 'off';
            app.PlotButton.Visible = 'off';
            pause(1);
            clear selectedData illDeaths totDeaths Ratio statelessCity
            fid = fopen('project.txt','w');
            num = 1; %Initializes variable used for indexing into the struct that isolates data
            minRat = '';
            selectedYear = Rangefunct(app); %Customizable: years that are selected by user interface
            selectedCity = CitiesValueChanged(app); %Customizable: cities that are selected by user interface
            for i = length(selectedCity)
                selectedCity{i} = convertCharsToStrings(selectedCity{i});
            end
            statelessCity(length(selectedCity)) = selectedCity{end}; %Preallocates a vector for cities with states
            for i = 1:length(selectedCity) %Removes the state designations
                statelessCity(i) = strtok(selectedCity{i},',');
            end 
            selectedData(length(app.structproj)) = app.structproj(1); %Preallocates a struct with isolated data 


            for z = 1:length(statelessCity) %Selects data points that match with each city selected
                for j = 1:length(selectedYear) %Selects data points that match with each year selected
                    for i = 1:length(app.structproj)
                        if (app.structproj(i).Year == selectedYear(j) && app.structproj(i).City == statelessCity(z)) 
                            selectedData(num) = app.structproj(i); 
                            num = num+1; %Variable for next index in selectedData struct
                        end

                   end
                end

                %Preallocates variables that will add a running sum of deaths for total and for illnesses
                totDeaths = 1:length(selectedYear); 
                illDeaths = 1:length(selectedYear); 


                for i = 1:length(selectedData) %Adds all of the total deaths in one year
                    for j = 1:length(selectedYear)
                        if selectedData(i).Year == selectedYear(j)
                            if isnan(selectedData(i).AllDeaths)
                                selectedData(i).AllDeaths = 0;
                            end
                            totDeaths(j) = totDeaths(j) + selectedData(i).AllDeaths;
                        end
                    end
                end

               for i = 1:length(selectedData) %Adds all of the illness deaths in one year
                   for j = 1:length(selectedYear)
                        if selectedData(i).Year == selectedYear(j)
                            if isnan(selectedData(i).PneumoniaAndInfluenzaDeaths)
                               selectedData(i).PneumoniaAndInfluenzaDeaths = 0;
                            end
                            illDeaths(j) = illDeaths(j) + selectedData(i).PneumoniaAndInfluenzaDeaths;
                       end
                   end
               end

               Ratio = 1:length(selectedYear); %Preallocates a variable for the ratio of illness deaths to total deaths
               totRat = 0;
               for i = 1:length(selectedYear) %Creates a vector with the ratios of illness deaths to total deaths for each year
                   Ratio(i) = illDeaths(i)/totDeaths(i);
                   totRat = Ratio(i);
               end
               avgRat = totRat/length(selectedYear);
               if isempty(minRat)
                    minRat = avgRat;
                    minCity = selectedCity(z);
               elseif avgRat < minRat
                    minRat = avgRat;
                    minCity = selectedCity(z);
               end 
               fprintf(fid,"The average ratio for %s is %.2f.\n",selectedCity{z},avgRat);
               hold(app.Graph, "on"); %Holds graph so data of cities can be compared
               plot(app.Graph, selectedYear,Ratio); %Plots the selected years against the ratio of deaths
               
               if length(selectedYear) < 10
                 xticks(app.Graph, selectedYear); %Sets x-axis to display years as integers (if there's greater than 10 elements, it does this automatically)
               end
               clear selectedData illDeaths totDeaths Ratio selectedCityNoState %Frees these variables to be used again
            end
            fprintf(fid,"\nThe minimum average ratio is %.2f at %s",minRat,minCity{1});
            fclose('all');
            legend(app.Graph,selectedCity) %Makes legend for each city
            app.Graph.Visible = 'on';
            app.Wait.Visible = 'off';
            app.ClearTableButton.Visible = 'on';
        end

        % Button pushed function: CollectDataButton
        function CollectDataButtonPushed(app, event)
            app.CollectDataButton.Visible = 'off'; %once collected, button is no longer necessary
            app.WelcomeText.Visible = 'off';
            app.Wait.Visible = 'on';
            pause(1);
            structp = webread("https://data.cdc.gov/api/views/mr8w-325u/rows.csv?accessType=DOWNLOAD"); %collects data
            app.structproj = table2struct(structp);
            app.Wait.Visible = 'off';
            app.CitiesListBox.Visible = 'on';
            app.StartYearSpinner.Visible = 'on';
            app.EndYearSpinner.Visible = 'on';
            app.TextArea.Visible = 'on';
            app.PlotButton.Visible = 'on';
            app.TimeRangeInfo.Visible = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Visible = 'off';
            app.TextArea.Value = {'Choose filters for data'};
            app.ClearTableButton = uibutton(app.UIFigure, 'push');
            app.ClearTableButton.ButtonPushedFcn = createCallbackFcn(app, @ClearTableButtonPushed, true);
            app.ClearTableButton.Visible = 'off';
            app.ClearTableButton.Text = 'Clear Table';
            app.Graph = uiaxes(app.UIFigure);
            title(app.Graph, '')
            xlabel(app.Graph, 'Year')
            ylabel(app.Graph, 'Ratio of Deaths')
            app.Graph.Visible = 'off';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Visible = 'off';
            app.PlotButton.Text = 'Plot';
            app.CitiesListBoxLabel = uilabel(app.UIFigure);
            app.CitiesListBoxLabel.Visible = 'off';
            app.CitiesListBoxLabel.Text = 'Cities';
            app.CitiesListBox = uilistbox(app.UIFigure);
            app.CitiesListBox.Items = {'Boston, MA', 'Hartford, CT', 'New Haven, CT', 'Providence, RI', 'Albany, NY', 'Buffalo, NY', 'New York, NY', 'Syracuse, NY', 'Philadelphia, PA', 'Pittsburgh, PA', 'Trenton, NJ', 'Chicago, IL', 'Cleveland, OH', 'Columbia, OH', 'Detroit, MI', 'Indianapolis, IN', 'Des Moines, IA', 'Kansas City, KS', 'Minneapolis, MN', 'Saint Louis, MO', 'Atlanta, GA', 'Baltimore, MD', 'Charlotte, NC', 'Richmond, VA', 'Miami, FL', 'Tampa, FL', 'Washington, DC', 'Birmingham, AL', 'Lexington, KY', 'Memphis, TN', 'Nashville, TN', 'Austin, TX', 'Dallas, TX', 'Houston, TX', 'Little Rock, AR', 'New Orleans, LA', 'Tulsa, OK', 'Albuquerque, NM', 'Colorado Springs, CO', 'Denver, CO', 'Las Vegas, NV', 'Phoenix, AZ', 'Tucson, AZ', 'Salt Lake City, UT', 'Honolulu, HI', 'Los Angeles, CA', 'San Diego, CA', 'San Francisco, CA', 'Portland, OR', 'Seattle, WA'};
            app.CitiesListBox.Multiselect = 'on';
            app.CitiesListBox.Visible = 'off';
            app.CitiesListBox.Value = {'Boston, MA'};
            app.StartYearSpinnerLabel = uilabel(app.UIFigure);
            app.StartYearSpinnerLabel.Visible = 'off';
            app.StartYearSpinnerLabel.Text = 'Start Year';
            app.StartYearSpinner = uispinner(app.UIFigure);
            app.StartYearSpinner.Limits = [1962 2015];
            app.StartYearSpinner.Editable = 'off';
            app.StartYearSpinner.Visible = 'off';
            app.StartYearSpinner.Value = 1962;
            app.EndYearSpinnerLabel = uilabel(app.UIFigure);
            app.EndYearSpinnerLabel.Visible = 'off';
            app.EndYearSpinnerLabel.Text = 'End Year';
            app.EndYearSpinner = uispinner(app.UIFigure);
            app.EndYearSpinner.Limits = [1963 2016];
            app.EndYearSpinner.Editable = 'off';
            app.EndYearSpinner.Visible = 'off';
            app.EndYearSpinner.Value = 1963;
            app.CollectDataButton = uibutton(app.UIFigure, 'push');
            app.CollectDataButton.ButtonPushedFcn = createCallbackFcn(app, @CollectDataButtonPushed, true);
            app.CollectDataButton.Text = 'Collect Data';
            app.WelcomeText = uitextarea(app.UIFigure);
            app.WelcomeText.Value = {'Welcome,'; ''; 'This app takes the ratio of deaths by Pneumonia and/or influenza vs overall deaths of many major US cities. You can take this data and plot it against time, in any time range you may wish from 1962 to 2016.'; ''; 'In order to start, please press the button below for the data to be collected. An internet connection is required.'};
            app.TimeRangeInfo = uitextarea(app.UIFigure);
            app.TimeRangeInfo.Visible = 'off';
            app.TimeRangeInfo.Value = {'(Some cities do not have data before 1981, if they are selected, the graph will be empty in that time region for those cities).'};
            app.Wait = uitextarea(app.UIFigure);
            app.Wait.Visible = 'off';
            app.Wait.Value = {'Please Wait...'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HW4_Code

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end